classdef LC2RC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TesttxtButton               matlab.ui.control.Button
        backfilterEditField         matlab.ui.control.NumericEditField
        backfilterEditFieldLabel    matlab.ui.control.Label
        frontfilterEditFieldLabel   matlab.ui.control.Label
        NextStepButton              matlab.ui.control.Button
        frontfilterEditField        matlab.ui.control.NumericEditField
        TextArea                    matlab.ui.control.TextArea
        SaveButton                  matlab.ui.control.Button
        DataProcessingOptionsLabel  matlab.ui.control.Label
        ProcessButton               matlab.ui.control.Button
        Tree                        matlab.ui.container.CheckBoxTree
        ChannelsincludedNode        matlab.ui.container.TreeNode
        Channel1Node                matlab.ui.container.TreeNode
        Channel2Node                matlab.ui.container.TreeNode
        Channel3Node                matlab.ui.container.TreeNode
        Channel4Node                matlab.ui.container.TreeNode
        Channel5Node                matlab.ui.container.TreeNode
        Channel6Node                matlab.ui.container.TreeNode
        Channel7Node                matlab.ui.container.TreeNode
        Channel8Node                matlab.ui.container.TreeNode
        Channel9Node                matlab.ui.container.TreeNode
        Channel10Node               matlab.ui.container.TreeNode
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        PlotR2R1RatioCheckBox       matlab.ui.control.CheckBox
        PlotforMuscleSpineCheckBox  matlab.ui.control.CheckBox
        NormalizeCheckBox           matlab.ui.control.CheckBox
        SavePlotasCSVCheckBox       matlab.ui.control.CheckBox
        IncludeR2CheckBox           matlab.ui.control.CheckBox
        DelayEditField              matlab.ui.control.NumericEditField
        DelayEditFieldLabel         matlab.ui.control.Label
        IntervalEditField           matlab.ui.control.NumericEditField
        IntervalEditFieldLabel      matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Phase = 1 % 1: window adjustment, 2 = data plotting 
        %Makesure WindowEditField is default 100 
        Labchart ; 
        mat_file_name ;
        main_folder_path = "C:\Users\fengy\Desktop\HM\DrSayenkoLab\FW_RC_Automation"; %I need to find a way to automatically detect and change this  
       
    end


    methods (Access = private)
        
        function plotRC(app)
            %Insert the plotting function here, pltot to the axes
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % When pushed, the button will plot depending on the phase the app is
            % in


            %TODO: once codes are migrated, repalce all variable names with
            % 'app.' properties 
            bool_normalize = app.NormalizeCheckBox;

            stim_interval = app.IntervalEditField.Value;
            delay = app.DelayEditField.Value; 
            front_filter = app.frontfilterEditField.Value;
            back_filter = app.backfilterEditField.Value;
            sampling_window = stim_interval - front_filter - back_filter;


            switch app.Phase
                case 1 % window adjusting
                    %should plot the window, and the delay, overlaying a
                    %sample piece of data (maybe channel 3 at one of the
                    %later amplitudes 

                    %Calls the plot RC function, but modified s.t. it takes
                    %in 
               
                case 2 %do plot rc
                   
                otherwise disp('error phase is not 1 or 2, call the programmer to debug')
            end 

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            

            %% Converting parameters from app designer inputs
            %TODO: make this more efficient
            stim_interval = app.IntervalEditField.Value;
            R1_delay = app.DelayEditField.Value; 
            front_filter = app.frontfilterEditField.Value;
            back_filter = app.backfilterEditField.Value;

            R2_delay = stim_interval + R1_delay;
            sampling_window = stim_interval - front_filter - back_filter;

            [~,channels_to_use] = ismember(app.Tree.CheckedNodes, app.ChannelsincludedNode.Children);
            channels_to_use = channels_to_use.';
            
            % for i = 1:length(app.ChannelsincludedNode.Children)
            %     node = app.ChannelsincludedNode.Children[i]
            % 
            %     if node.
            %     channels_to_use = [channels_to_use, ]
            % end

            app.TextArea.Value = num2str(channels_to_use); %Sanity Check, that node operations are working 


            % %TODO: more advanced function 
            % AutomationDraft(app.FilePathEditField.Value);

            %% Preprocessing 
            %Load adi into path
            addpath adinstruments_sdk_matlab-master
            
            %%% File path parsing 
            %If the string contains the symbols \"\", get rid of them
            app.FilePathEditField.Value = erase(app.FilePathEditField.Value, ["'", '"'] );
            
            %If no extension, look for file_name.mat first, then
            %file_name.adicht in the given directory. 
            if ~isfile(app.FilePathEditField.Value)
               if isfile([app.FilePathEditField.Value,'.mat'])
                   app.FilePathEditField.Value = [app.FilePathEditField.Value,'.mat'];
                   
               elseif isfile([app.FilePathEditField.Value,'.adicht'])
                  app.FilePathEditField.Value = [app.FilePathEditField.Value,'.adicht'];

               else 
                   error("file extension incorrect or file does not exist, please double check input");
                end
            end


            %TODO: parse the filepath for its extensions 
            [~, file_name, ext] = fileparts(app.FilePathEditField.Value);
           
            % Preprocesses the data if .adicht file
            if strcmp(ext, ".adicht")  
                app.Labchart = load(AlexConvert(app.FilePathEditField.Value, app.main_folder_path)); % This should load everything into workspace 
                app.TextArea.Value = 'Loaded adicht file into Labchart';

            % Load data if .mat file, but first attempt to save to input  
            elseif strcmp(ext, ".mat")

                newfilepath = strcat(app.main_folder_path, '/Input/' , file_name, '.mat');
                if ~strcmp(app.FilePathEditField.Value, newfilepath)
                    copyfile(app.FilePathEditField.Value, newfilepath);
                end
                app.Labchart = load(app.FilePathEditField.Value); % Load matlab file into App memory 
                app.TextArea.Value = 'Loaded mat file into Labchart';           

                %If the matlab file is bad, there will be error thrown
                %later 

            else 
                error("File of incorrect extension, only .adicht and .mat files are supported")
            end

            %% Extract Labchart Fields 

            Data = app.Labchart.Labchart.Data          ;
            file_meta = app.Labchart.Labchart.file_meta     ;
            comments = app.Labchart.Labchart.comments      ;
            record_meta = app.Labchart.Labchart.record_meta   ;
            channel_meta = app.Labchart.Labchart.channel_meta  ;

            %Here, print to the textarea 

            app.TextArea.Value = 'New file loading completed...';

            
            % === Stimulation Location Determination ===
            % DONE
            % 1. identify the peaks in channel 2
                % This is done by normalizing channel 2 to be 0 to 1, every spot
                % greater than .8 is going to be a stimulation
                % associate the amplitude comment to the nearest 2 stimulations 
                % Alternatively, assuming that channel 2 format is standard, we can
                % forgo normalizing and just find spots greater than 1
            % 3. notate each stimulation in the comments section  
            % NExt step, is to make sure the new format can determine R1 & r2
            
            % determine the records we care about, to reduce running time 
            records = unique([comments.record]);
            irrelevant_records = [];
            
            % Go through each relevant record and determine all the stimulations by
            % looking at channel 2
            % Then add each of the stimulations to comments
            for idx = 1:length(records)
                i = records(idx);
            
                % extract channel 2 in the given record
                chnl2 = Data{1,i}(2,:); % ith column of the Data cell, everything in row 2
                % find the indices of stimulations as a list (i.e. tick position) 
                indices = find(chnl2 > 2); 
            
                if width(indices) == 0
                    irrelevant_records(end+1) = i;
                    continue
                end
            
                %Remove repetitive stimulations
                diffs = diff(indices);
                boundaries = [1 , find(diffs>stim_interval+3) + 1];
                filtered_indices = indices(boundaries);
                
                % for loop add them as str = 'stim', tick_position = index, record = i
                len = length(comments);
                for j = 1:length(filtered_indices)
                    %index = filtered_indices(j)
                    len = len + 1;
                    comments(len).str = 'stim';
                    comments(len).tick_position = filtered_indices(j);
                    comments(len).record = i;
                end
            end
            
            records = setdiff(records,irrelevant_records);
            
            clearvars filtered_indices indices diffs boundaries i idx j irrelevant_records

            app.TextArea.Value = "Relevant records identified as " + int2str(records);

            
            %% Filter away repetitive comments
            % Create unique keys for all elements in comments
            fprintf('\n ===== Filtering Comments =====\n\n' );
            
            keys = arrayfun(@(x) sprintf('%s_%d_%s', x.tick_position, x.record), comments, "UniformOutput",false);
            
            %find unique elements based on the keys
            [~, uniqueIdx] = unique(keys, 'stable');
            
            % FInally, filter out repetitions 
            filteredComments = comments(uniqueIdx);
            
            clearvars keys uniqueIdx
            
            
            %% Format data
            
            fprintf('\n ===== Formatting Data =====\n\n' );
            
            % Convert all amplitudes into double from string
            for i = 1:numel(filteredComments)
                %First, filter out everything in the "str" field that is not a double
                if ~isempty(regexp(filteredComments(i).str, '^-?\d+$', 'once'))
                    filteredComments(i).str = str2double(filteredComments(i).str);
                end
            end
            
            %Sort data first accroding to record, then according to tick_position
            record = [filteredComments.record];
            tick_pos = [filteredComments.tick_position];
            
            [~,sortIdx] = sortrows([record(:), ...
                            tick_pos(:)], ...
                            [1,2]);
            filteredComments = filteredComments(sortIdx);
            
            clearvars sortIdx tick_pos record
            
            
            %% Peak to peak calculation
            
            %DONE: Figure out how to determine this for other datasets
            
            % TODO: remove this line, as it has been rendered obsolete by new way of
            % doing things, assuming data is sufficiently clean and I don't have to do
            % more cleaning here 
            % filteredComments = filteredComments(arrayfun(@(x) ismember(x.record,records), filteredComments));
            
            
            fprintf('\n ===== Finding Peak to Peak =====\n\n' );
            
            % For each comment:
            for i = 1:numel(filteredComments)
                if ischar(filteredComments(i).str)
                    x = filteredComments(i);
            %For each stimulation under the amplitude
            %   Find in data{record}, the max - min in the area tick+130 to tick+230
            %   Find in data{record}, the max - min in the area tick+R1_delay+front_filter to tick+R1_delay+front_filter+sampling_window 
            
                    % DONE: parameterize 3:10, which stands for the channels
                    window_startr1 = x.tick_position+R1_delay+front_filter;
                    window_endr1 = window_startr1 + sampling_window;
            
                    windowsr1 = Data{x.record}(channels_to_use, window_startr1:window_endr1);
                    maxminr1 = max(windowsr1, [],2) - min(windowsr1,[],2);
            
                    window_startr2 = x.tick_position+R2_delay+front_filter;
                    window_endr2 = window_startr2 + sampling_window;
            
                    windowsr2 = Data{x.record}(channels_to_use, window_startr2:window_endr2);
                    maxminr2 = max(windowsr2, [],2) - min(windowsr2,[],2);
            %   Store this information to maxminr1 field of filteredcomments
                    filteredComments(i).maxminr1 = maxminr1;
                    filteredComments(i).maxminr2 = maxminr2;
                %else, the fields are empty --> []
            
                end 
            end
            
            fprintf('\n ===== Raw Peak to Peak Data Calculated =====\n\n' );
            
            
            clearvars R1_delay R2_delay windowsr2 windowsr1 maxminr2 maxminr1 x i window_size
            
            %% Maxmin calculation
            
            %TODO: make sure that the positional comments are integrated into the name
            %of the stimulations
            
            
            %   Then, while averaging each max-min, merge elements with same record, amplitude,
            %   and stimulation
            
            % Output 
            
            fprintf('\n ===== Calculating Peak to Peak related data =====\n\n' );
            
            lastamp = 1;
            last_loc = '';
            
            
            rows_to_remove = [];
            
            for i = 1:numel(filteredComments)
                x = filteredComments(i);
                if ischar(x.str)
                    if strcmp(x.str, 'stim')
                        filteredComments(lastamp).sumr1 = filteredComments(lastamp).sumr1 + x.maxminr1;
                        filteredComments(lastamp).sumr2 = filteredComments(lastamp).sumr2 + x.maxminr2;
                        filteredComments(lastamp).count = filteredComments(lastamp).count + 1;
                        filteredComments(lastamp).record_name = last_loc;
                    else 
                        last_loc = x.str;
                        rows_to_remove(end+1) = i;
                    end
            
                elseif isnumeric(x.str)
                    lastamp = i;
                    filteredComments(lastamp).sumr1 = 0;
                    filteredComments(lastamp).sumr2 = 0;
                    filteredComments(lastamp).count = 0;
                    filteredComments(lastamp).record_name = '';
                   
                end 
            end
            
            %Remove irrelevant rows from filtered comments
            
            filteredComments(rows_to_remove) = [];
            
            %Outputting
            
            %filter array to only have amplitude left
            %TODO, change the name output here 
            output = filteredComments(arrayfun(@(x) isnumeric(x.str), filteredComments));
            
            %Sanity check
            % if len(output.averager1) == len(channels_to_use)
            
            % else fprintf('\n ERROR! Mismatch between channels \n\n')
            
            % end
            fprintf('\n ===== Relevant Data Calculated =====\n\n' );
            
            clearvars lastamp
            
            %% Data cleaning
            
            fprintf('\n ===== Preparing Output =====\n\n' );
            
            
            %Calculate average & store into new struct 
            channel_names = string({channel_meta(channels_to_use).name});
            
            
            % Assume each amp value has the "record_name" field 
            record_names = unique({output.record_name}); %For some reason this gives you results in a very random order
            
            % Final Calculation
            newstructr1 = struct( 'amps', {output.str});
            newstructr2 = struct( 'amps', {output.str});
            
            
            for i = 1:numel(output)
                output(i).averager1 = output(i).sumr1 / output(i).count;
                output(i).averager2 = output(i).sumr2 / output(i).count;
                newstructr1(i).("record_name") = output(i).record_name;
                newstructr2(i).("record_name") = output(i).record_name;
            
                for j = 1:numel(output(i).averager1)
                    %store into new struct 
                    % TODO: add parameters that indicate which fields are needed
            
                    % TODO: add checks to include other information
            
                    newstructr1(i).(channel_names(j)) = output(i).averager1(j);
                    newstructr2(i).(channel_names(j)) = output(i).averager2(j);
                end
            end 
            % Should Process the data
            % Should create nodes in the channels included tree
            % Should show raw comments to error check
            % Shou


        end

        % Button pushed function: TesttxtButton
        function TesttxtButtonPushed(app, event)
            [~,channels_to_use] = ismember(app.Tree.CheckedNodes, app.ChannelsincludedNode.Children);
            channels_to_use = channels_to_use.';

            app.TextArea.Value = num2str(channels_to_use);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.IntervalEditFieldLabel = uilabel(app.UIFigure);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.IntervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntervalEditField.Value = 100;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DelayEditField.Value = 130;
            app.IncludeR2CheckBox = uicheckbox(app.UIFigure);
            app.IncludeR2CheckBox.Text = 'Include R2';
            app.IncludeR2CheckBox.Value = true;
            app.SavePlotasCSVCheckBox = uicheckbox(app.UIFigure);
            app.SavePlotasCSVCheckBox.Text = 'Save Plot as CSV';
            app.SavePlotasCSVCheckBox.Value = true;
            app.NormalizeCheckBox = uicheckbox(app.UIFigure);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.PlotforMuscleSpineCheckBox = uicheckbox(app.UIFigure);
            app.PlotforMuscleSpineCheckBox.Text = 'Plot for Muscle / Spine';
            app.PlotR2R1RatioCheckBox = uicheckbox(app.UIFigure);
            app.PlotR2R1RatioCheckBox.Text = 'Plot R2/R1 Ratio ';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.Value = 'C:\Users\fengy\Desktop\HM\20240826_RTA006_EPA1_RC ONLY.adicht';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.ChannelsincludedNode = uitreenode(app.Tree);
            app.ChannelsincludedNode.Text = 'Channels included';
            app.Channel1Node = uitreenode(app.ChannelsincludedNode);
            app.Channel1Node.Text = 'Channel1';
            app.Channel2Node = uitreenode(app.ChannelsincludedNode);
            app.Channel2Node.Text = 'Channel2';
            app.Channel3Node = uitreenode(app.ChannelsincludedNode);
            app.Channel3Node.Text = 'Channel3';
            app.Channel4Node = uitreenode(app.ChannelsincludedNode);
            app.Channel4Node.Text = 'Channel4';
            app.Channel5Node = uitreenode(app.ChannelsincludedNode);
            app.Channel5Node.Text = 'Channel5';
            app.Channel6Node = uitreenode(app.ChannelsincludedNode);
            app.Channel6Node.Text = 'Channel6';
            app.Channel7Node = uitreenode(app.ChannelsincludedNode);
            app.Channel7Node.Text = 'Channel7';
            app.Channel8Node = uitreenode(app.ChannelsincludedNode);
            app.Channel8Node.Text = 'Channel8';
            app.Channel9Node = uitreenode(app.ChannelsincludedNode);
            app.Channel9Node.Text = 'Channel9';
            app.Channel10Node = uitreenode(app.ChannelsincludedNode);
            app.Channel10Node.Text = 'Channel10';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Channel3Node, app.Channel4Node, app.Channel5Node, app.Channel6Node, app.Channel7Node, app.Channel8Node, app.Channel9Node, app.Channel10Node];
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.DataProcessingOptionsLabel = uilabel(app.UIFigure);
            app.DataProcessingOptionsLabel.Text = 'Data Processing Options';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.TextArea = uitextarea(app.UIFigure);
            app.frontfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.frontfilterEditField.Value = 20;
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.Text = 'Next Step';
            app.frontfilterEditFieldLabel = uilabel(app.UIFigure);
            app.frontfilterEditFieldLabel.Text = 'frontfilter';
            app.backfilterEditFieldLabel = uilabel(app.UIFigure);
            app.backfilterEditFieldLabel.Text = 'backfilter';
            app.backfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.backfilterEditField.Value = 15;
            app.TesttxtButton = uibutton(app.UIFigure, 'push');
            app.TesttxtButton.ButtonPushedFcn = createCallbackFcn(app, @TesttxtButtonPushed, true);
            app.TesttxtButton.Text = 'Testtxt';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LC2RC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end